classdef layers_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        TabGroup                     matlab.ui.container.TabGroup
        LoadTab                      matlab.ui.container.Tab
        GridLayout2                  matlab.ui.container.GridLayout
        DrawButton_2                 matlab.ui.control.Button
        LoadBackgroundPanel          matlab.ui.container.Panel
        GridLayout6_2                matlab.ui.container.GridLayout
        LoadButton_3                 matlab.ui.control.Button
        LoadImagePanel               matlab.ui.container.Panel
        GridLayout6                  matlab.ui.container.GridLayout
        LoadButton                   matlab.ui.control.Button
        StatusPanel                  matlab.ui.container.Panel
        GridLayout7                  matlab.ui.container.GridLayout
        UITable                      matlab.ui.control.Table
        ImageTab                     matlab.ui.container.Tab
        GridLayout2_2                matlab.ui.container.GridLayout
        SaveButton                   matlab.ui.control.Button
        LayersPanel                  matlab.ui.container.Panel
        GridLayout8                  matlab.ui.container.GridLayout
        DrawButton                   matlab.ui.control.Button
        ImageDataPanel               matlab.ui.container.Panel
        GridLayout3_2                matlab.ui.container.GridLayout
        DeleteBackgroundButton       matlab.ui.control.Button
        LoadButton_2                 matlab.ui.control.Button
        ShowLegendCheckBox           matlab.ui.control.CheckBox
        ImageTitleEditField          matlab.ui.control.EditField
        ImageTitleEditFieldLabel     matlab.ui.control.Label
        GridLayout5                  matlab.ui.container.GridLayout
        ColorbarAxisMax              matlab.ui.control.NumericEditField
        ColorbarAxisMin              matlab.ui.control.NumericEditField
        ColorbarAxisScaleLabel       matlab.ui.control.Label
        ColorbarTitleEditField       matlab.ui.control.EditField
        ColorbarTitleEditFieldLabel  matlab.ui.control.Label
        ShowColorbarCheckBox         matlab.ui.control.CheckBox
        GridLayout4                  matlab.ui.container.GridLayout
        BValue                       matlab.ui.control.Spinner
        GValue                       matlab.ui.control.Spinner
        RValue                       matlab.ui.control.Spinner
        ColorForNaNLabel             matlab.ui.control.Label
        BackgroundImageLabel         matlab.ui.control.Label
        LayerDataPanel               matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        ChooseFrameSlider            matlab.ui.control.Slider
        ChooseFrameLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        ImageLoaderComponentHandler ImageLoaderComponent
        ImageComponentHandler ImageComponentHandler
        ImageDisplayHandler ImageDisplayHandler
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %TODO addpath
            search_path = '../*/classDefinitions';
            while isempty(dir(search_path))
                search_path = ['../', search_path];
            end
            addpath(dir(search_path).folder) 
            imageBuilder=ImageBuilder;
            app.ImageDisplayHandler=ImageDisplayHandler;
            app.ImageComponentHandler=ImageComponentHandler(app.ImageDisplayHandler,imageBuilder, app);
            app.ImageLoaderComponentHandler=ImageLoaderComponent(app.ImageComponentHandler,imageBuilder);
            
            %to move
            app.ImageTab.Parent=[];
            app.ImageLoaderComponentHandler.updateStatusTable(app);
            app.DrawButton_2.Enable=false;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.ImageLoaderComponentHandler.loadImageData(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.ImageDisplayHandler.forceCloseFigures();
            delete(app);
        end

        % Value changed function: ChooseFrameSlider
        function ChooseFrameSliderValueChanged(app, event)
            value = app.ChooseFrameSlider.Value;
            app.ImageDisplayHandler.setFrame(app, round(value));
        end

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
            app.ImageComponentHandler.renderFromInput(app);
        end

        % Button pushed function: LoadButton_3
        function LoadButton_3Pushed(app, event)
            app.ImageLoaderComponentHandler.loadBackground(app);
        end

        % Button pushed function: DrawButton_2
        function DrawButton_2Pushed(app, event)
            app.ImageLoaderComponentHandler.showLoadedData(app);
        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)
            app.ImageComponentHandler.loadBackground();
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.ImageComponentHandler.saveFigures();
        end

        % Button pushed function: DeleteBackgroundButton
        function DeleteBackgroundButtonPushed(app, event)
            app.ImageComponentHandler.deleteBackground();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.LoadTab = uitab(app.TabGroup);
            app.LoadTab.Title = 'Load';
            app.GridLayout2 = uigridlayout(app.LoadTab);
            app.StatusPanel = uipanel(app.GridLayout2);
            app.StatusPanel.Title = 'Status';
            app.GridLayout7 = uigridlayout(app.StatusPanel);
            app.UITable = uitable(app.GridLayout7);
            app.UITable.ColumnName = {''; 'Loaded'; 'Frames'};
            app.UITable.RowName = {};
            app.LoadImagePanel = uipanel(app.GridLayout2);
            app.LoadImagePanel.Title = 'Load Image';
            app.GridLayout6 = uigridlayout(app.LoadImagePanel);
            app.LoadButton = uibutton(app.GridLayout6, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.LoadBackgroundPanel = uipanel(app.GridLayout2);
            app.LoadBackgroundPanel.Title = 'Load Background';
            app.GridLayout6_2 = uigridlayout(app.LoadBackgroundPanel);
            app.LoadButton_3 = uibutton(app.GridLayout6_2, 'push');
            app.LoadButton_3.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_3Pushed, true);
            app.LoadButton_3.Text = 'Load';
            app.DrawButton_2 = uibutton(app.GridLayout2, 'push');
            app.DrawButton_2.ButtonPushedFcn = createCallbackFcn(app, @DrawButton_2Pushed, true);
            app.DrawButton_2.Text = 'Draw';
            app.ImageTab = uitab(app.TabGroup);
            app.ImageTab.Title = 'Image';
            app.GridLayout2_2 = uigridlayout(app.ImageTab);
            app.ChooseFrameLabel = uilabel(app.GridLayout2_2);
            app.ChooseFrameLabel.Text = 'Choose Frame:';
            app.ChooseFrameSlider = uislider(app.GridLayout2_2);
            app.ChooseFrameSlider.ValueChangedFcn = createCallbackFcn(app, @ChooseFrameSliderValueChanged, true);
            app.LayerDataPanel = uipanel(app.GridLayout2_2);
            app.LayerDataPanel.Title = 'Layer Data';
            app.GridLayout3 = uigridlayout(app.LayerDataPanel);
            app.ImageDataPanel = uipanel(app.GridLayout2_2);
            app.ImageDataPanel.Title = 'Image Data';
            app.GridLayout3_2 = uigridlayout(app.ImageDataPanel);
            app.BackgroundImageLabel = uilabel(app.GridLayout3_2);
            app.BackgroundImageLabel.Text = 'Background Image';
            app.ColorForNaNLabel = uilabel(app.GridLayout3_2);
            app.ColorForNaNLabel.Text = 'Color For NaN';
            app.GridLayout4 = uigridlayout(app.GridLayout3_2);
            app.RValue = uispinner(app.GridLayout4);
            app.RValue.Step = 0.1;
            app.RValue.Limits = [0 1];
            app.GValue = uispinner(app.GridLayout4);
            app.GValue.Step = 0.1;
            app.GValue.Limits = [0 1];
            app.BValue = uispinner(app.GridLayout4);
            app.BValue.Step = 0.1;
            app.BValue.Limits = [0 1];
            app.ShowColorbarCheckBox = uicheckbox(app.GridLayout3_2);
            app.ShowColorbarCheckBox.Text = 'Show Colorbar';
            app.ColorbarTitleEditFieldLabel = uilabel(app.GridLayout3_2);
            app.ColorbarTitleEditFieldLabel.Text = 'Colorbar Title';
            app.ColorbarTitleEditField = uieditfield(app.GridLayout3_2, 'text');
            app.ColorbarAxisScaleLabel = uilabel(app.GridLayout3_2);
            app.ColorbarAxisScaleLabel.Text = 'Colorbar Axis Scale';
            app.GridLayout5 = uigridlayout(app.GridLayout3_2);
            app.ColorbarAxisMin = uieditfield(app.GridLayout5, 'numeric');
            app.ColorbarAxisMax = uieditfield(app.GridLayout5, 'numeric');
            app.ImageTitleEditFieldLabel = uilabel(app.GridLayout3_2);
            app.ImageTitleEditFieldLabel.Text = 'Image Title';
            app.ImageTitleEditField = uieditfield(app.GridLayout3_2, 'text');
            app.ShowLegendCheckBox = uicheckbox(app.GridLayout3_2);
            app.ShowLegendCheckBox.Text = 'Show Legend';
            app.LoadButton_2 = uibutton(app.GridLayout3_2, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Text = 'Load';
            app.DeleteBackgroundButton = uibutton(app.GridLayout3_2, 'push');
            app.DeleteBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteBackgroundButtonPushed, true);
            app.DeleteBackgroundButton.Text = 'Delete Background';
            app.DrawButton = uibutton(app.GridLayout2_2, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Text = 'Draw';
            app.LayersPanel = uipanel(app.GridLayout2_2);
            app.LayersPanel.Title = 'Layers';
            app.GridLayout8 = uigridlayout(app.LayersPanel);
            app.SaveButton = uibutton(app.GridLayout2_2, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = layers_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end